classdef ModalitiesTask1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AxialAxes                matlab.ui.control.UIAxes
        SaggitalAxes             matlab.ui.control.UIAxes
        CoronalAxes              matlab.ui.control.UIAxes
        BrowseDicomImagesButton  matlab.ui.control.Button
        MDRMultiplanarReconsructionLabel  matlab.ui.control.Label
    end

    
     properties (Access = public)
        images % images
        x  % axial
        y  % Coronal
        z  % Sagittal
       
     end
     methods (Access = public)
        function loadImages(app)
            
            % set center axes to center
            s = size(app.images);
            app.x = s(1);
            app.y = s(2);
            app.z = s(3);
            
            % plot sections
            figure;
            plotAxial(app);
            close
            
            figure;
            plotCoronal(app);
            close
            
            figure;
            plotSaggital(app);
            close
        end
        
        function plotAxial(app)
            % plot Axial
            for i=1:app.z
                imshow(squeeze(app.images(:,:,i)),[]);
            end
        end
        function plotSaggital(app)
            % plot Saggital
            for i=1:app.y
            imshow(squeeze(app.images(:,i,:)),[]);
            end
        end
        function plotCoronal(app)
            % plot Coronal
            for i=1:app.x
            imshow(squeeze(app.images(i,:,:)),[]);
            end
        end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseDicomImagesButton
        function BrowseDicomImagesButtonPushed(app, event)
            try
            % Browse images folders
            path = uigetdir();
            
            % load images
            files =dir(fullfile(path,'*.dcm'));
            for i=1:length(files)
%                 disp(i);
                Img_name = files(i).name;
                Img = dicomread([path '\' Img_name]);
                app.images(:,:,i) = mat2gray(Img);
            end
                        
             % load images data and plot them
            loadImages(app)
            catch
            % handle corrubted images error
            errordlg('Loading Error')
            
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxialAxes = uiaxes(app.UIFigure);
            title(app.AxialAxes, 'Axial')
            xlabel(app.AxialAxes, 'X')
            ylabel(app.AxialAxes, 'Y')
            app.AxialAxes.Visible = 'off';
            app.SaggitalAxes = uiaxes(app.UIFigure);
            title(app.SaggitalAxes, 'Saggital')
            xlabel(app.SaggitalAxes, 'X')
            ylabel(app.SaggitalAxes, 'Y')
            app.SaggitalAxes.Visible = 'off';
            app.CoronalAxes = uiaxes(app.UIFigure);
            title(app.CoronalAxes, 'Coronal')
            xlabel(app.CoronalAxes, 'X')
            ylabel(app.CoronalAxes, 'Y')
            app.CoronalAxes.Visible = 'off';
            app.BrowseDicomImagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseDicomImagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDicomImagesButtonPushed, true);
            app.BrowseDicomImagesButton.Text = 'Browse Dicom Images';
            app.MDRMultiplanarReconsructionLabel = uilabel(app.UIFigure);
            app.MDRMultiplanarReconsructionLabel.Text = 'MDR(Multiplanar Reconsruction)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalitiesTask1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end