classdef task4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ObliqueAxis              matlab.ui.control.UIAxes
        SaggitalAxis             matlab.ui.control.UIAxes
        CoronalAxis              matlab.ui.control.UIAxes
        AxialAxis                matlab.ui.control.UIAxes
        AxialLabel               matlab.ui.control.Label
        CoronalShowOblique       matlab.ui.control.Button
        CoronalShowAxis          matlab.ui.control.Button
        SaggitalShowOblique      matlab.ui.control.Button
        SaggitalShowAxis         matlab.ui.control.Button
        AxialShowOblique         matlab.ui.control.Button
        AxialShowAxis            matlab.ui.control.Button
        BrowseDicomImagesButton  matlab.ui.control.Button
        SaggitalLabel            matlab.ui.control.Label
        CoronalLabel             matlab.ui.control.Label
        ObliqueLabel             matlab.ui.control.Label
        Label                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        images % images
        oblique % oblique 
        x  % axial
        y  % Coronal
        z  % Sagittal
        ox % oblique axial
        axisaxial
    end
    properties (Access = public)
        AHL % Axial Horizontal Line
        AVL % Axial Vertical Line
        CHL % Coronal Horizontal Line
        CVL % Coronal Vertical Line
        SHL % Saggital Horizontal Line
        SVL % Saggital Vertical Line
    end
    methods (Access = public)
        function loadImages(app)
            
            % set center axis to center
            s = size(app.images);
            app.axisaxial = s(3);
            app.x = floor(s(1)/2);
            app.y = floor(s(2)/2);
            app.z = floor(s(3)/2);
            
            % resize Axial axis to fit images
            app.AxialAxis.XLim = [0 s(1)];
            app.AxialAxis.YLim = [0 s(2)];
            disableDefaultInteractivity(app.AxialAxis);
            
            % resize Coronal axis to fit images
            app.CoronalAxis.XLim = [0 s(2)];
            app.CoronalAxis.YLim = [0 s(3)];
            disableDefaultInteractivity(app.CoronalAxis);
            
            % resize Saggital axis to fit images
            app.SaggitalAxis.XLim = [0 s(1)];
            app.SaggitalAxis.YLim = [0 s(3)];
            disableDefaultInteractivity(app.SaggitalAxis);
            
            % plot sections
            plotAxial(app);
            plotCoronal(app);
            plotSaggital(app)            
        end
        
        function plotAxial(app)
            % plot Axial
%             axial = squeeze(app.images(:,:,app.axisaxial-app.z));
%             axialRot = imrotate(axial,180);
            imshow(squeeze(app.images(:,:,app.axisaxial-app.z)),'Parent',app.AxialAxis)
        end
        function plotSaggital(app)
            % plot Saggital
            sagital = squeeze(app.images(:,app.y,:));
            sagitalRot = imrotate(sagital,90);
            imshow(sagitalRot,'Parent',app.SaggitalAxis)
        end
        function plotCoronal(app)
            % plot Coronal
            coronal = squeeze(app.images(app.x,:,:));
            coronalRot = imrotate(coronal,90);
            imshow(coronalRot,'Parent',app.CoronalAxis)
        end
        function plotOblique(app,plotArea,h_axis,v_axis,d_axis,mode)
            % plot Oblique
            oLine=drawline(plotArea,"Color",'green');
            function updateOblique(src,event)
                p1 = event.CurrentPosition(1,:);
                p2 = event.CurrentPosition(2,:);
                % calc slop and y intersection
                slop = round(((p2(2)-p1(2))/(p2(1)-p1(1))),4);
                c = p1(2) - slop*p1(1);
                % calc angle
                angle = atand(slop);
                
                % calc distance between line and origin
                distance =sign(c)*floor(GetPointLineDistance(app,0,0,p1(1),p1(2),p2(1),p2(2)));
                % diagonal length
                diag = floor(sqrt(h_axis^2 + v_axis^2));
                
                %% rotate x and y to original axis
                % xr = x * cosd(angle) - y * sind(angle)
                xr = max(round((1:diag) .* cosd(angle)- distance * sind(angle)),1);
                % yr = x * sind(angle) + y * cosd(angle)
                yr = max(round((1:diag) .* sind(angle)+ distance * cosd(angle)),1);
                
                % validate x rotate and y rotate
                xr = xr(xr<=h_axis);
                yr = yr(yr<=v_axis);
                minSize = min(max(size(xr)),max(size(yr)));
                xr = xr(1:minSize);
                yr = yr(1:minSize);
                
                % calc oblique plan by concatinate rows in oblique line
                obliquePlan = zeros([d_axis minSize]);
                for i=1:minSize
                    % validate oblique fron Axial, Coronal or Saggital
                    if strcmp(mode,'A')
                        obliquePlan(:,i) = squeeze(app.images(yr(i),xr(i),(1:d_axis)));
                    elseif strcmp(mode,'C')
                        obliquePlan(:,i) = squeeze(app.images((1:d_axis),yr(i),xr(i)));
                    elseif strcmp(mode,'S')
                        obliquePlan(:,i) = squeeze(app.images(yr(i),(1:d_axis),xr(i)));
                    end
                end
                % plot obique plan
                obliquee = squeeze(obliquePlan).';
                obliqueRot = imrotate(obliquee, 90);
                disableDefaultInteractivity(app.ObliqueAxis);
                imshow(obliqueRot,'Parent',app.ObliqueAxis)
            end
            addlistener(oLine,'ROIMoved',@updateOblique);
            addlistener(oLine,'MovingROI',@updateOblique);
            evt.CurrentPosition = oLine.Position;
            updateOblique('-',evt);
        end
        function distance = GetPointLineDistance(app,xOrg,yOrg,x1,y1,x2,y2)
            % Find the numerator for our point-to-line distance formula.
            numerator = abs((x2 - x1) * (y1 - yOrg) - (x1 - xOrg) * (y2 - y1));
        
            % Find the denominator for our point-to-line distance formula.
            denominator = sqrt((x2 - x1) ^ 2 + (y2 - y1) ^ 2);
        
            % Compute the distance.
            distance = numerator ./ denominator;
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function Image_2Clicked(app, event)
            
        end

        % Callback function
        function Image_3Clicked(app, event)
            
        end

        % Button pushed function: BrowseDicomImagesButton
        function BrowseDicomImagesButtonPushed(app, event)

        try
            %path = './series-00000';
            % Browse images folders
            path = uigetdir()
            % load images
            files =dir(fullfile(path,'*.dcm'));
            for i=1:length(files)
                disp(i);
                Img_name = files(i).name;
                Img = dicomread([path '\' Img_name]);
                app.images(:,:,i) = mat2gray(Img);
            end
                        
             % load images data and plot them
            loadImages(app);
            catch ME
            % handle corrubted images error
            errordlg('Loading Error')
            
        end


        end

        % Callback function
        function SliderValueChanging(app, event)
            
        end

        % Callback function
        function Slider2ValueChanging(app, event)
            
        end

        % Callback function: AxialAxis
        function AxialAxisButtonDown(app, event)
            
        end

        % Callback function: ObliqueAxis
        function drawslowobliqueButtonPushed(app, event)
            % oblique with another structure 
            % fast in transition, very slow in rotation because of we rotate
            % the whole 3D matrix
            oLine=drawline(app.AxialAxis);
            function updateOblique(src,event)
                [xxx yyy zzz] = size(app.images);
                center = [floor(xxx/2) floor(yyy/2)];
                p1 = event.CurrentPosition(1,:)-center;
                p2 = event.CurrentPosition(2,:)-center;
                pv1 = event.PreviousPosition(1,:);
                pv2 = event.PreviousPosition(2,:);
                slop = round(((p2(2)-p1(2))/(p2(1)-p1(1))),4);
                c = p1(2) - slop*p1(1);
                prevSlop = round(((pv2(2)-pv1(2))/(pv2(1)-pv1(1))),4);
                if slop ~= prevSlop && strcmp(event.EventName,'ROIMoved')
                    angle = atand(slop);
                    % rotate image 
                    app.oblique = imrotate3(app.images,angle,[0 0 1]);
                end
                [xx yy zz] = size(app.oblique);
                distance = floor(GetPointLineDistance(app,0,0,p1(1),p1(2),p2(1),p2(2)));
                if c < 0
                    distance = abs(distance);
                else
                    distance = -distance;
                end
                
                app.ox =  distance + floor(xx/2);
                imshow(squeeze(app.oblique(app.ox,:,:)),'Parent',app.ObliqueAxis)


            end
            addlistener(oLine,'ROIMoved',@updateOblique);
            addlistener(oLine,'MovingROI',@updateOblique);
        end

        % Button pushed function: AxialShowAxis
        function AxialShowAxisPushed(app, event)
            % draw Axial Axis lines
            mainSize=size(app.images);
            function hAxialMove(src,event)
                app.x = round(src.Position(1,2));
                app.SVL.Position = [app.x 0;app.x mainSize(3)];
                plotCoronal(app);
            end
            function vAxialMove(src,event)
                app.y = round(src.Position(1,1));
                app.CVL.Position = [app.y 0;app.y mainSize(3)];
                plotSaggital(app);
            end
            app.AHL=drawline(app.AxialAxis,'Position',[0 app.x;mainSize(2) app.x],'Color','red','InteractionsAllowed','translate');
            app.AVL=drawline(app.AxialAxis,'Position',[app.y 0;app.y mainSize(1)],'InteractionsAllowed','translate');
            addlistener(app.AHL,'MovingROI',@hAxialMove);
            addlistener(app.AVL,'MovingROI',@vAxialMove);
        end

        % Button pushed function: SaggitalShowAxis
        function SaggitalShowAxisPushed(app, event)
            % draw Saggital Axis lines
            mainSize=size(app.images);
            function hAxialMove(src,event)
                app.z = round(src.Position(1,2));
                app.CHL.Position = [0 app.z;mainSize(1) app.z];
                plotAxial(app);
            end
            function vAxialMove(src,event)
                app.x = round(src.Position(1,1));
                app.AHL.Position = [0 app.x;mainSize(2) app.x];
                plotCoronal(app);
            end
            app.SHL=drawline(app.SaggitalAxis,'Position',[0 app.z;mainSize(2) app.z],'Color','yellow','InteractionsAllowed','translate');
            app.SVL=drawline(app.SaggitalAxis,'Position',[app.x 0;app.x mainSize(3)],'Color','red','InteractionsAllowed','translate');
            addlistener(app.SHL,'MovingROI',@hAxialMove);
            addlistener(app.SVL,'MovingROI',@vAxialMove);
        end

        % Button pushed function: CoronalShowAxis
        function CoronalShowAxisPushed(app, event)
            % draw Coronal Axis lines
            mainSize=size(app.images);
            function hAxialMove(src,event)
                app.z = round(src.Position(1,2));
                app.SHL.Position = [0 app.z;mainSize(2) app.z];
                plotAxial(app);
            end
            function vAxialMove(src,event)
                app.y = round(src.Position(1,1));
                app.AVL.Position = [app.x 0;app.x mainSize(2)];
                plotSaggital(app);
            end
            app.CHL=drawline(app.CoronalAxis,'Position',[0 app.z;mainSize(1) app.z],'Color','yellow','InteractionsAllowed','translate');
            app.CVL=drawline(app.CoronalAxis,'Position',[app.y 0;app.y mainSize(3)],'InteractionsAllowed','translate');
            addlistener(app.CHL,'MovingROI',@hAxialMove);
            addlistener(app.CVL,'MovingROI',@vAxialMove);
        end

        % Button pushed function: AxialShowOblique
        function AxialShowObliquePushed(app, event)
            % draw Axial oblique line
            [x_axis, y_axis, z_axis] = size(app.images);
            plotOblique(app,app.AxialAxis,x_axis,y_axis,z_axis,"A");
        end

        % Button pushed function: SaggitalShowOblique
        function SaggitalShowObliquePushed(app, event)
            % draw Saggital oblique line
            [x_axis, y_axis, z_axis] = size(app.images);
            plotOblique(app,app.SaggitalAxis,x_axis,z_axis,y_axis,"S");
        end

        % Button pushed function: CoronalShowOblique
        function CoronalShowObliquePushed(app, event)
            % draw Coronal oblique line
            [x_axis, y_axis, z_axis] = size(app.images);
            plotOblique(app,app.CoronalAxis,y_axis,z_axis,x_axis,"C");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ObliqueAxis = uiaxes(app.UIFigure);
            title(app.ObliqueAxis, '')
            xlabel(app.ObliqueAxis, 'X')
            ylabel(app.ObliqueAxis, 'Y')
            app.ObliqueAxis.XTick = [];
            app.ObliqueAxis.YTick = [];
            app.ObliqueAxis.Visible = 'off';
            app.SaggitalAxis = uiaxes(app.UIFigure);
            title(app.SaggitalAxis, '')
            xlabel(app.SaggitalAxis, 'X')
            ylabel(app.SaggitalAxis, 'Z')
            app.SaggitalAxis.XTick = [];
            app.SaggitalAxis.YTick = [];
            app.SaggitalAxis.Visible = 'off';
            app.CoronalAxis = uiaxes(app.UIFigure);
            title(app.CoronalAxis, '')
            xlabel(app.CoronalAxis, 'Y')
            ylabel(app.CoronalAxis, 'Z')
            app.CoronalAxis.XTick = [];
            app.CoronalAxis.YTick = [];
            app.CoronalAxis.Visible = 'off';
            app.AxialAxis = uiaxes(app.UIFigure);
            title(app.AxialAxis, '')
            xlabel(app.AxialAxis, 'Y')
            ylabel(app.AxialAxis, 'X')
            app.AxialAxis.YLim = [0 1];
            app.AxialAxis.XTick = [];
            app.AxialAxis.YTick = [];
            app.AxialAxis.Visible = 'off';
            app.AxialLabel = uilabel(app.UIFigure);
            app.AxialLabel.Text = 'Axial';
            app.CoronalShowOblique = uibutton(app.UIFigure, 'push');
            app.CoronalShowOblique.ButtonPushedFcn = createCallbackFcn(app, @CoronalShowObliquePushed, true);
            app.CoronalShowOblique.Text = 'Draw Oblique';
            app.CoronalShowAxis = uibutton(app.UIFigure, 'push');
            app.CoronalShowAxis.ButtonPushedFcn = createCallbackFcn(app, @CoronalShowAxisPushed, true);
            app.CoronalShowAxis.Text = 'Draw Axes';
            app.SaggitalShowOblique = uibutton(app.UIFigure, 'push');
            app.SaggitalShowOblique.ButtonPushedFcn = createCallbackFcn(app, @SaggitalShowObliquePushed, true);
            app.SaggitalShowOblique.Text = 'Draw Oblique';
            app.SaggitalShowAxis = uibutton(app.UIFigure, 'push');
            app.SaggitalShowAxis.ButtonPushedFcn = createCallbackFcn(app, @SaggitalShowAxisPushed, true);
            app.SaggitalShowAxis.Text = 'Draw Axes';
            app.AxialShowOblique = uibutton(app.UIFigure, 'push');
            app.AxialShowOblique.ButtonPushedFcn = createCallbackFcn(app, @AxialShowObliquePushed, true);
            app.AxialShowOblique.Text = 'Draw Oblique';
            app.AxialShowAxis = uibutton(app.UIFigure, 'push');
            app.AxialShowAxis.ButtonPushedFcn = createCallbackFcn(app, @AxialShowAxisPushed, true);
            app.AxialShowAxis.Text = 'Draw Axes';
            app.BrowseDicomImagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseDicomImagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDicomImagesButtonPushed, true);
            app.BrowseDicomImagesButton.Text = 'Browse Dicom Images';
            app.SaggitalLabel = uilabel(app.UIFigure);
            app.SaggitalLabel.Text = 'Saggital';
            app.CoronalLabel = uilabel(app.UIFigure);
            app.CoronalLabel.Text = 'Coronal';
            app.ObliqueLabel = uilabel(app.UIFigure);
            app.ObliqueLabel.Text = 'Oblique';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end